classdef ECG_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ECGFilteringModuleUsingDiscreteWaveletTransformationLabel  matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        LoadButton                      matlab.ui.control.Button
        AnalyzeButton                   matlab.ui.control.Button
        AxisOriginalECG                 matlab.ui.control.UIAxes
        SamplingRateEditFieldLabel      matlab.ui.control.Label
        SamplingRateEditField           matlab.ui.control.EditField
        AxisFilteredECG                 matlab.ui.control.UIAxes
        AveragePowerofECGSignalLabel    matlab.ui.control.Label
        WLabel                          matlab.ui.control.Label
        ecgPow                          matlab.ui.control.EditField
        AveragePowerofNoiseSignalLabel  matlab.ui.control.Label
        dBEditFieldLabel_2              matlab.ui.control.Label
        noisePow                        matlab.ui.control.EditField
        SignaltoNoiseRatioSNRLabel      matlab.ui.control.Label
        dBEditFieldLabel_3              matlab.ui.control.Label
        SNRdB                           matlab.ui.control.EditField
        HeartRateLabel                  matlab.ui.control.Label
        dBEditFieldLabel_4              matlab.ui.control.Label
        HeartRate                       matlab.ui.control.EditField
    end

    
    methods (Access = private)
        
        function [filteredEcg, ecgNoise] = ecgfilterdb8(app, ecgSignal)

            %db8 filtering
            waveletFunction = 'db8';
            [C,L] = wavedec(ecgSignal,8,waveletFunction);

            D1 = wrcoef('d',C,L,waveletFunction,1);
            D2 = wrcoef('d',C,L,waveletFunction,2);
            D3 = wrcoef('d',C,L,waveletFunction,3);
            D4 = wrcoef('d',C,L,waveletFunction,4);
            D5 = wrcoef('d',C,L,waveletFunction,5); 
            D6 = wrcoef('d',C,L,waveletFunction,6); 
            D7 = wrcoef('d',C,L,waveletFunction,7); 
            D8 = wrcoef('d',C,L,waveletFunction,8);


            filteredEcg = D4+D5+D6+D7+D8;
            ecgNoise = D1+D2+D8;
    
        end
        
        function [Rpeaks,locs] = peakdetectsym4(app,ecgSignal,noOfSamples)

            %Peak Detection Using Symlet
            waveletSym = modwt(ecgSignal,4,'sym4');
            waveletRec = zeros(size(waveletSym));
            waveletRec(3:4,:) = waveletSym(3:4,:);

            peakData = imodwt(waveletRec,'sym4');
            peakData = abs(peakData).^2;
            avg = mean(peakData);
            [Rpeaks, locs] = findpeaks(peakData,noOfSamples,'MinPeakHeight',avg*8,'MinPeakDistance',50);
    
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, pathName] = uigetfile;
            filePath = strcat(pathName,fileName);
            app.EditField.Value = filePath;
        end

        % Button pushed function: AnalyzeButton
        function AnalyzeButtonPushed(app, event)
            cla(app.AxisFilteredECG);
            cla(app.AxisOriginalECG);
            samplingRate = app.SamplingRateEditField.get.Value;
            samplingRate = str2num(samplingRate);
            a = app.EditField.Value;    
            load(a);
            ecgSignal = val(1:1:samplingRate*5);
            ecgSignal = ecgSignal./200;
            noOfSamples = 1:length(ecgSignal);
            signalTime =(0:length(ecgSignal)-1)/samplingRate;
            plot(app.AxisOriginalECG,signalTime,ecgSignal);
            
            [filteredEcg,ecgNoise] = ecgfilterdb8(app,ecgSignal);

            [Rpeaks,locs] = peakdetectsym4(app,ecgSignal,noOfSamples);

            numOfBeats = length(locs);
            timeLimit = length(ecgSignal)/samplingRate;
            heartRate = (numOfBeats*60)/timeLimit;
            heartRate = int8(heartRate);
            ecgSNR = db(snr(filteredEcg, ecgNoise));
            app.SNRdB.Value = string(ecgSNR);
            app.HeartRate.Value = string(heartRate);
            ecgPower = rms(filteredEcg)^2;
            noisePower = rms(ecgNoise)^2;
            %ecgPower = db(ecgPower);
            app.ecgPow.Value = string(ecgPower);
            app.noisePow.Value = string(noisePower);
            
            plot(app.AxisFilteredECG ,signalTime, filteredEcg);
            hold(app.AxisFilteredECG,"on")
            plot(app.AxisFilteredECG ,locs./samplingRate,Rpeaks.*0,"r*");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ECGFilteringModuleUsingDiscreteWaveletTransformationLabel = uilabel(app.UIFigure);
            app.ECGFilteringModuleUsingDiscreteWaveletTransformationLabel.Text = 'ECG Filtering Module Using Discrete Wavelet Transformation';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.AnalyzeButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeButtonPushed, true);
            app.AnalyzeButton.Text = 'Analyze';
            app.AxisOriginalECG = uiaxes(app.UIFigure);
            title(app.AxisOriginalECG, 'Collected ECG')
            xlabel(app.AxisOriginalECG, 'time (s)')
            ylabel(app.AxisOriginalECG, 'Amplitude (mV)')
            app.SamplingRateEditFieldLabel = uilabel(app.UIFigure);
            app.SamplingRateEditFieldLabel.Text = 'Sampling Rate';
            app.SamplingRateEditField = uieditfield(app.UIFigure, 'text');
            app.AxisFilteredECG = uiaxes(app.UIFigure);
            title(app.AxisFilteredECG, 'Filtered ECG')
            xlabel(app.AxisFilteredECG, 'time (s)')
            ylabel(app.AxisFilteredECG, 'Amplitude (mV)')
            app.AveragePowerofECGSignalLabel = uilabel(app.UIFigure);
            app.AveragePowerofECGSignalLabel.Text = 'Average Power of ECG Signal';
            app.WLabel = uilabel(app.UIFigure);
            app.WLabel.Text = 'W';
            app.ecgPow = uieditfield(app.UIFigure, 'text');
            app.AveragePowerofNoiseSignalLabel = uilabel(app.UIFigure);
            app.AveragePowerofNoiseSignalLabel.Text = 'Average Power of Noise Signal';
            app.dBEditFieldLabel_2 = uilabel(app.UIFigure);
            app.dBEditFieldLabel_2.Text = 'W';
            app.noisePow = uieditfield(app.UIFigure, 'text');
            app.SignaltoNoiseRatioSNRLabel = uilabel(app.UIFigure);
            app.SignaltoNoiseRatioSNRLabel.Text = 'Signal to Noise Ratio (SNR)';
            app.dBEditFieldLabel_3 = uilabel(app.UIFigure);
            app.dBEditFieldLabel_3.Text = 'dB';
            app.SNRdB = uieditfield(app.UIFigure, 'text');
            app.HeartRateLabel = uilabel(app.UIFigure);
            app.HeartRateLabel.Text = 'Heart Rate';
            app.dBEditFieldLabel_4 = uilabel(app.UIFigure);
            app.dBEditFieldLabel_4.Text = 'Bits Per Second';
            app.HeartRate = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ECG_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end