classdef Tumor_Analyzer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        AxisInput          matlab.ui.control.UIAxes
        AxisDetect         matlab.ui.control.UIAxes
        ClassifyButton     matlab.ui.control.Button
        EditFieldClassify  matlab.ui.control.EditField
        TumorDetectionfromMRIImagesLabel  matlab.ui.control.Label
        EditFieldLoad      matlab.ui.control.EditField
        LoadButton         matlab.ui.control.Button
        DetectButton       matlab.ui.control.Button
    end

    
    methods (Access = private)
        
        function diff_im = anisodiff(app, im, num_iter, delta_t, kappa, option)
        fprintf('Removing Noise\n');
        fprintf('filtering complete');
    
        im = double(im);
        diff_im = im;
    
        dx = 1;
        dy = 1;
        dd = sqrt(2);
    
        hN = [0 1 0; 0 -1 0; 0 0 0];
        hS = [0 0 0; 0 -1 0; 0 1 0];
        hE = [0 0 0; 0 -1 1; 0 0 0];
        hW = [0 0 0; 1 -1 0; 0 0 0];
        hNE = [0 0 1; 0 -1 0; 0 0 0];
        hSE = [0 0 0; 0 -1 0; 0 0 1];
        hSW = [0 0 0; 0 -1 0; 1 0 0];
        hNW = [1 0 0; 0 -1 0; 0 0 0];
    
        for t = 1:num_iter
        
            nablaN = imfilter(diff_im,hN,'conv');
            nablaS = imfilter(diff_im,hS,'conv');
            nablaW = imfilter(diff_im,hW,'conv');
            nablaE = imfilter(diff_im,hE,'conv');
            nablaNE = imfilter(diff_im,hNE,'conv');
            nablaSE = imfilter(diff_im,hSE,'conv');
            nablaSW = imfilter(diff_im,hSW,'conv');
            nablaNW = imfilter(diff_im,hNW,'conv');
        
            if option == 1
                cN = exp(-(nablaN/kappa).^2);
                cS = exp(-(nablaS/kappa).^2);
                cW = exp(-(nablaW/kappa).^2);
                cE = exp(-(nablaE/kappa).^2);
                cNE = exp(-(nablaNE/kappa).^2);
                cSE = exp(-(nablaSE/kappa).^2);
                cSW = exp(-(nablaSW/kappa).^2);
                cNW = exp(-(nablaNW/kappa).^2);
            elseif option == 2
                cN = 1./(1 + (nablaN/kappa).^2);
                cS = 1./(1 + (nablaS/kappa).^2);
                cW = 1./(1 + (nablaW/kappa).^2);
                cE = 1./(1 + (nablaE/kappa).^2);
                cNE = 1./(1 + (nablaNE/kappa).^2);
                cSE = 1./(1 + (nablaSE/kappa).^2);
                cSW = 1./(1 + (nablaSW/kappa).^2);
                cNW = 1./(1 + (nablaNW/kappa).^2);
            end
        
            diff_im = diff_im + delta_t * (...
                        (1/(dy^2))*cN.*nablaN + (1/(dy^2))*cS.*nablaS +...
                        (1/(dx^2))*cW.*nablaW + (1/(dx^2))*cE.*nablaE +...
                        (1/(dd^2))*cNE.*nablaNE + (1/(dd^2))*cSE.*nablaSE +...
                        (1/(dd^2))*cSW.*nablaSW + (1/(dd^2))*cNW.*nablaNW );
                    
        end
        end
        
        function tumorType = tumorClassifier(app,s)

            imgSets = [imageSet(fullfile('C:\ProgramData\MathWorks\webapps\R2019a\apps\train','\brain')),...
                        imageSet(fullfile('C:\ProgramData\MathWorks\webapps\R2019a\apps\train','\mamma')),...
                        imageSet(fullfile('C:\ProgramData\MathWorks\webapps\R2019a\apps\train','\abdomen'))];

            
            minSetCount = min([imgSets.Count]);
            imgSets = partition(imgSets,minSetCount,'randomize');

            [trainingSets, testSets] = partition(imgSets, 0.9, 'randomize');

            bag = bagOfFeatures(trainingSets);

            categoryClassifier = trainImageCategoryClassifier(trainingSets,bag);

            [labelIdx, scores] = predict(categoryClassifier, s);

            categoryClassifier.Labels(labelIdx);
            tumorType = labelIdx; 
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            [fileName, pathName] = uigetfile('*.jpg','Select an Image');
            filePath = strcat(pathName,fileName);
            app.EditFieldLoad.Value = filePath;
        end

        % Button pushed function: DetectButton
        function DetectButtonPushed(app, event)
            cla(app.AxisInput);
            cla(app.AxisDetect);
            app.EditFieldClassify.Value = '';
            
            a = app.EditFieldLoad.Value;
            s = imread(a);
            
            s2 = imresize(s, [512,512]);
            imshow(s2,'Parent',app.AxisInput);
            
            %Filter

            num_iter = 10;
            delta_t = 1/7;
            kappa = 15;
            option = 2;
            inp = anisodiff(app, s, num_iter, delta_t, kappa, option);
            inp = uint8(inp);

            inp = imresize(inp, [512,512]);
            if size(inp,3)>1
                inp = rgb2gray(inp);
            end
            
            %thresholding

            sout = imresize(inp, [512,512]);
            t0 = 60;
            th = t0+(max(inp(:))+ min(inp(:)))./2;
            for i = 1:1:size(inp,1)
                for j = 1:1:size(inp,2)
                    if inp(i,j)>th
                        sout(i,j) = 1;
                    else
                        sout(i,j) = 0;
                    end
                end
            end

            label = bwlabel(sout);
            stats = regionprops(logical(sout),'Solidity','Area','BoundingBox');
            density = [stats.Solidity];
            area = [stats.Area];

            high_dense_area = density>0.5;
            max_area = max(area(high_dense_area));
            tumor_label = find(area==max_area);
            tumor = ismember(label,tumor_label);

            if max_area>100
                box = stats(tumor_label);
                wantedBox = box.BoundingBox;

                rgb = inp(:,:,[1 1 1]);
                red = rgb(:,:,1);
                red(tumor) = 256;
                green = rgb(:,:,2);
                green(tumor) = 0;
                blue = rgb(:,:,3);
                blue(tumor) = 0;

                tumorOutline(:,:,1) = red;
                tumorOutline(:,:,2) = green;
                tumorOutline(:,:,3) = blue;

                imshow(tumorOutline,'Parent',app.AxisDetect);
                hold(app.AxisDetect,"on");
                rectangle(app.AxisDetect,'Position',wantedBox,'EdgeColor','y');
            else
                app.EditFieldClassify.Value = 'No Tumor Detected';
                imshow(s2,'Parent',app.AxisDetect);
                return;
            end

        end

        % Button pushed function: ClassifyButton
        function ClassifyButtonPushed(app, event)
            c = app.EditFieldClassify.Value;
            d = isempty(c);
            if d == 1
                b = app.EditFieldLoad.Value;
                s2 = imread(b);
            
                tumorType = tumorClassifier(app,s2);
                if tumorType == 1
                    app.EditFieldClassify.Value = 'Brain Tumor';
                elseif tumorType == 2
                    app.EditFieldClassify.Value = 'Breast Tumor';
                elseif tumorType == 3
                    app.EditFieldClassify.Value = 'Abdominal Tumor';
                else
                    app.EditFieldClassify.Value = 'Not Classified';
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.AxisInput = uiaxes(app.UIFigure);
            title(app.AxisInput, 'Main Image')
            xlabel(app.AxisInput, '')
            ylabel(app.AxisInput, '')
            app.AxisDetect = uiaxes(app.UIFigure);
            title(app.AxisDetect, 'Tumor Detection')
            xlabel(app.AxisDetect, '')
            ylabel(app.AxisDetect, '')
            app.ClassifyButton = uibutton(app.UIFigure, 'push');
            app.ClassifyButton.ButtonPushedFcn = createCallbackFcn(app, @ClassifyButtonPushed, true);
            app.ClassifyButton.Text = 'Classify';
            app.EditFieldClassify = uieditfield(app.UIFigure, 'text');
            app.TumorDetectionfromMRIImagesLabel = uilabel(app.UIFigure);
            app.TumorDetectionfromMRIImagesLabel.Text = 'Tumor Detection from MRI Images';
            app.EditFieldLoad = uieditfield(app.UIFigure, 'text');
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.DetectButton = uibutton(app.UIFigure, 'push');
            app.DetectButton.ButtonPushedFcn = createCallbackFcn(app, @DetectButtonPushed, true);
            app.DetectButton.Text = 'Detect';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Tumor_Analyzer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end